classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Label              matlab.ui.control.Label
        EndResetButton     matlab.ui.control.Button
        PauseResumeButton  matlab.ui.control.Button
        StartButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        t
        startTime
        pausedTime = 0
        isRunning = false
    end
    
    methods (Access = private)

        function startupFcn(app)

            app.t = timer;
            app.t.ExecutionMode = 'fixedRate';
            app.t.Period = 1;
            app.t.TimerFcn = @(~,~) updateTimer(app);

        end

        % Timer function (runs every second)
        function updateTimer(app)

            elapsed = seconds(datetime('now') - app.startTime);
            elapsed = floor(elapsed);
        
            minutes = floor(elapsed/60);
            secondsPart = mod(elapsed,60);
        
            app.Label.Text = sprintf('%02d:%02d', minutes, secondsPart);

        end
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.StartButton.Text = "Running...";
            app.startTime = datetime('now');
            
            
            app.t = timer;
            app.t.ExecutionMode = 'fixedRate';
            app.t.Period = 1;
            app.t.TimerFcn = @(~,~) updateTimer(app);
        
            start(app.t)
        
            app.isRunning = true;
            app.PauseResumeButton.Text = "Pause";
 
        end

        % Button pushed function: PauseResumeButton
        function PauseResumeButtonPushed(app, event)
            if app.isRunning
                % Pause
                stop(app.t)
                app.pausedTime = seconds(datetime('now') - app.startTime);
                app.PauseResumeButton.Text = "Resume";
                app.isRunning = false;
        
            else
        
                % Resume
                app.startTime = datetime('now') - seconds(app.pausedTime);
                start(app.t)
                app.PauseResumeButton.Text = "Pause";
                app.isRunning = true;
        
            end
        end

        % Button pushed function: EndResetButton
        function EndResetButtonPushed(app, event)
            if ~isempty(app.t)
                stop(app.t)
                delete(app.t)
            end
        
            app.pausedTime = 0;
            app.isRunning = false;
        
            app.Label.Text = "00:00";
            app.PauseResumeButton.Text = "Pause";
            app.StartButton.Text = "Start Revising";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.PauseResumeButton = uibutton(app.UIFigure, 'push');
            app.PauseResumeButton.ButtonPushedFcn = createCallbackFcn(app, @PauseResumeButtonPushed, true);
            app.PauseResumeButton.Text = 'Pause / Resume';
            app.EndResetButton = uibutton(app.UIFigure, 'push');
            app.EndResetButton.ButtonPushedFcn = createCallbackFcn(app, @EndResetButtonPushed, true);
            app.EndResetButton.Text = 'End / Reset';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '00:00';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end